classdef ExplorePointCloud < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExplorePointCloudUIFigure      matlab.ui.Figure
        GridLayout1                    matlab.ui.container.GridLayout
        BottomTabGroup                 matlab.ui.container.TabGroup
        ControlsTab                    matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        ObjectsListPanel               matlab.ui.container.Panel
        DeleteSelectedButton           matlab.ui.control.Button
        ObjectListBox                  matlab.ui.control.ListBox
        SizeDropDown                   matlab.ui.control.DropDown
        SizeDropDownLabel              matlab.ui.control.Label
        ExportSelectedButton           matlab.ui.control.Button
        EgoPositionPanel               matlab.ui.container.Panel
        CamPosLabel                    matlab.ui.control.Label
        CamTargetDropDown              matlab.ui.control.DropDown
        CamTargetDropDownLabel         matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        XpositionLabel                 matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        ZEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        RepositionButton               matlab.ui.control.Button
        NavigationParametersPanel      matlab.ui.container.Panel
        CamRollSlider                  matlab.ui.control.Slider
        CamRollSliderLabel             matlab.ui.control.Label
        CamPanSlider                   matlab.ui.control.Slider
        CamPanSliderLabel              matlab.ui.control.Label
        CamZoomSlider                  matlab.ui.control.Slider
        ZoomFactorLabel                matlab.ui.control.Label
        CamDollySlider                 matlab.ui.control.Slider
        CamDollySliderLabel            matlab.ui.control.Label
        OutputMessagePanel             matlab.ui.container.Panel
        LaunchAnalyzePointCloudButton  matlab.ui.control.Button
        OutputMsg                      matlab.ui.control.TextArea
        PointCloudInfoPanel            matlab.ui.container.Panel
        LogCheckBox                    matlab.ui.control.CheckBox
        LogCommandsLabel               matlab.ui.control.Label
        BackgroundColorDropDown        matlab.ui.control.DropDown
        BackgroundColorDropDownLabel   matlab.ui.control.Label
        CurrentLocationTextArea        matlab.ui.control.TextArea
        CurrentLocationLabel           matlab.ui.control.Label
        TotalPointCountEditField       matlab.ui.control.NumericEditField
        TotalPointCountEditFieldLabel  matlab.ui.control.Label
        MapLimitsTextArea              matlab.ui.control.TextArea
        MapLimitsLabel                 matlab.ui.control.Label
        InstructionsTab                matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        InstructionImage               matlab.ui.control.Image
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        % Hyperparameters
        MouseXRatio = 0.1; % Horizontal turning motion to mouse motion ratio
        MouseYRatio = 0.1; % Vertical turning motion to mouse motion ratio
        TimerPeriod = 0.02; % Refresh rate of cursor function
        
        PointSize % Size of ROI analyzed when selecting an object
        PointDensity % Minimum amount of points in ROI for object to be selected
        ObjectSizes % Size of the cuboids used to select objects (Small, Medium, Large)

        % Point Cloud Properties
        pc % Point cloud data loaded and displayed
        pcColor % Colors of the points in the point cloud
        MapXLim % [min, max] x-value
        MapYLim % [min, max] y-value
        MapZLim % [min, max] z-value
        CameraDistance % Distance between camera position and target
        ScopeHandle % Handle to the Scope plotted in middle to guide user's aim
        pcHandleMap % Handle to map pcshow 
        CuboidHandle % Handle to the cuboids of ROI
        

        % Cursor Properties
        Timer % Timer sets the refresh rate for cursor navigation
        FigureMid % Middle point of the figure
        PointerPos

        % Logging Properties
        formatSpec = '%.3g';
        NavCommand = "";% Last navigation command executed

        % Selection Properties
        CurrObjectInd = [];
        CurrObjectROI = [];
        ObjectNumTot = 0;
        ObjectNameCount = 0;
        ObjectROICell = {};
        ObjectIndCell = {};
        SelectedColor = [255, 0, 0]; %ROI selection is Red 
        SavedColor = [0, 255, 0]; %Saved selection is Green
        ListedName = '';
        VoxNet = [];


    end
    
    methods (Access = private)

        % Set Up Functions

        function setPointCloudParameters(app,pc)
            % Extract information
            app.pc = pc;
            app.MapXLim = double(pc.XLimits);
            app.MapYLim = double(pc.YLimits);
            app.MapZLim = double(pc.ZLimits);


            % Set target distance based on order of magnitude of map size (e.g. [10 - 100] size has distance = 1) 
            avgMapSize = mean([diff(app.MapXLim), diff(app.MapYLim), diff(app.MapZLim)]);
            MapRatio = avgMapSize / 100;
            app.CameraDistance = MapRatio; 
            app.PointSize =  1 * MapRatio;
            app.PointDensity = 1 * MapRatio;
            app.ObjectSizes = [5 10 20] .* MapRatio;
            

            % Display information
            app.MapLimitsTextArea.Value = sprintf(['\n X Limits:\t[' num2str(app.MapXLim(1),app.formatSpec) '  ' num2str(app.MapXLim(2),app.formatSpec) ...
                ']\n\n Y Limits:\t[' num2str(app.MapYLim(1),app.formatSpec) '  ' num2str(app.MapYLim(2),app.formatSpec) ']\n\n Z Limits:\t[' ...
                num2str(app.MapZLim(1),app.formatSpec) '  ' num2str(app.MapZLim(2),app.formatSpec) ']']);
            app.TotalPointCountEditField.Value = pc.Count;
        end

        function setRepositionParameters(app)
            app.XEditField.Value = round(mean(app.MapXLim),3);
            app.YEditField.Value = round(mean(app.MapYLim),3);
            app.ZEditField.Value = round(mean(app.MapZLim),3);
        end

        function setObjectParameters(app)
            app.SizeDropDown.ItemsData = app.ObjectSizes;
        end

        
        % Display Functions

        function displayAxes(app)
            %Perform an initial search to build the KD tree during startup
            dialogBar = uiprogressdlg(app.ExplorePointCloudUIFigure, 'Title', 'Building KD Tree', ...
                'Indeterminate','on');
            drawnow
            sampleROI = [0, 0.01, 0, 0.01, 0, 0.01];
            findPointsInROI(app.pc,sampleROI);
            close(dialogBar)
            % Plot the point cloud and the pointer target
            if isempty(app.pc.Color)
                app.pc.Color = uint8(ones(size(app.pc.Location)) .* 100);
            end
            app.pcHandleMap = pcshow(app.pc, "Parent", app.UIAxes);
            app.UIAxes.Projection = 'perspective';
            rotate3d(app.UIAxes, 'off');
            app.UIAxes.Toolbar.Visible = 'off';
            app.pcColor = app.pcHandleMap.Children.CData;

            hold(app.UIAxes, "on");
            app.ScopeHandle = plot3(app.UIAxes, 1, 1, 1, 'k+', 'Markersize', 18, 'LineWidth', 2);
            hold(app.UIAxes, "off");
            app.BackgroundColorDropDownValueChanged;
            app.changePosition;
        end

        function changePosition(app)
            CamPos = [app.XEditField.Value, app.YEditField.Value, app.ZEditField.Value];
            CamAng = 90;
            TarDir = app.CamTargetDropDown.Value == app.CamTargetDropDown.ItemsData;
            CamTar = CamPos + [TarDir, 0] .* app.CameraDistance;
            campos(app.UIAxes, CamPos);
            camva(app.UIAxes, CamAng);
            camtarget(app.UIAxes, CamTar);
            camup(app.UIAxes, [0 0 1]);
            app.setCurrentLocation;
            app.setScopeLocation;

            app.NavCommand = "campos(app.UIAxes, [" + num2str(CamPos(1)) + ", " + num2str(CamPos(2)) + ", " + num2str(CamPos(3)) + ...
                "]); camtarget(app.UIAxes, [" + num2str(CamTar(1)) + ", " + num2str(CamTar(2)) + ", " + num2str(CamTar(3)) + ...
                "]); camva(app.UIAxes, " + num2str(CamAng) + ");";
            app.logCommands;
        end

        function setCurrentLocation(app)
            CamPos = app.UIAxes.CameraPosition;
            app.CurrentLocationTextArea.Value = sprintf(['\n X Position:\t' num2str(CamPos(1), app.formatSpec) '\n\n Y Position:\t' num2str(CamPos(2), app.formatSpec) '\n\n Z Position:\t' num2str(CamPos(3), app.formatSpec)]);
        end

        function setScopeLocation(app)
            CamTar = app.UIAxes.CameraTarget;
            app.ScopeHandle.XData = CamTar(1);
            app.ScopeHandle.YData = CamTar(2);
            app.ScopeHandle.ZData = CamTar(3);
        end
        
        % Cursor Functions

        function setTimer(app)
            app.Timer = timer("ExecutionMode", 'fixedRate', 'Period', app.TimerPeriod, "TasksToExecute", inf); 
            app.Timer.TimerFcn = @app.cursorView;
            app.ExplorePointCloudUIFigure.PointerShapeCData = NaN(16);
        end

        function cursorView(app, ~, ~)
            newCursorLoc = get(0,'PointerLocation');
            CursorDisplacement = fix(newCursorLoc - app.FigureMid);
            campan(app.UIAxes, CursorDisplacement(1)* app.MouseXRatio,0);
            campan(app.UIAxes, 0, CursorDisplacement(2) * app.MouseYRatio);
            app.setScopeLocation;
            set(groot, 'PointerLocation', app.FigureMid);
        end
        
        function enableCursor(app)
            app.setFigurePostion;
            set(groot, 'PointerLocation', app.FigureMid);

            app.ExplorePointCloudUIFigure.Pointer = 'custom';
            app.OutputMsg.FontColor = "k";
            app.OutputMsg.Value = "Mouse Commands Enabled!";
            start(app.Timer);
        end
        
        function disableCursor(app)
            app.ExplorePointCloudUIFigure.Pointer = 'arrow';
            app.OutputMsg.FontColor = "k";
            app.OutputMsg.Value = "Mouse Commands Disabled!";
            stop(app.Timer);
        end

        function setFigurePostion(app)
            FigurePosition = app.ExplorePointCloudUIFigure.Position;
            app.FigureMid = round([FigurePosition(1) + FigurePosition(3) /2 , FigurePosition(2) + FigurePosition(4)/2]);
        end
        
        % Logging Functions

        function logCommands(app)
            if app.LogCheckBox.Value
                app.OutputMsg.FontColor = "k";
                app.OutputMsg.Value = app.NavCommand;
            end
        end

        % Selection Functions

        function [midROI, inMap, CamDiff] = findObject(app)
            % Find direction user is looking at and normalize it
            CamTar = app.UIAxes.CameraTarget;
            CamPos = app.UIAxes.CameraPosition;
            CamDiff = CamTar- CamPos;
            CamDiffLog = CamDiff > 0;

            midROI = CamTar - CamDiff * app.PointSize /4; 
            ROI = zeros(1,6);
            nInd = 0;
            minPointsNum = app.PointDensity;
            inMap = true; 
            while nInd < minPointsNum && inMap
                midROI = midROI + CamDiff * app.PointSize /4;
                ROI([1, 3, 5]) = midROI + (CamDiff - app.CameraDistance)/ app.CameraDistance  * app.PointSize / 2;
                ROI([2, 4, 6]) = ROI([1 3 5]) + app.PointSize;
                
                indROI = findPointsInROI(app.pc,ROI);
                nInd = length(indROI);
                % Check if still withing point cloud limits
                if CamDiffLog(1)
                    inMapX = midROI(1) <= app.MapXLim(2);
                else
                    inMapX = midROI(1) >= app.MapXLim(1);
                end
                if CamDiffLog(2)
                    inMapY = midROI(2) <= app.MapYLim(2);
                else
                    inMapY = midROI(2) >= app.MapYLim(1);
                end
                if CamDiffLog(3)
                    inMapZ = midROI(3) <= app.MapZLim(2);
                else
                    inMapZ = midROI(3) >= app.MapZLim(1);
                end
                inMap = inMapX && inMapY && inMapZ;
            end
        end
    
        function moveToObject(app)
            
            [midROI, inMap, CamDiff] = app.findObject;
            if inMap
                newCamPos = midROI - CamDiff;
                newCamTar = midROI;
                campos(app.UIAxes, newCamPos);
                camtarget(app.UIAxes, newCamTar);
                app.setCurrentLocation;
                
                app.OutputMsg.FontColor = "k";
                app.OutputMsg.Value = "Position changed successfully!";

                app.NavCommand = "campos(app.UIAxes, [" + num2str(newCamPos(1), app.formatSpec) + ", " + num2str(newCamPos(2), app.formatSpec) + ", "  ...  
                    + num2str(newCamPos(3), app.formatSpec) + "]); camtarget(app.UIAxes, [" + num2str(newCamTar(1), app.formatSpec) + ", " ... 
                    + num2str(newCamTar(2), app.formatSpec) + ", " + num2str(newCamTar(3), app.formatSpec) + "]);";
                app.logCommands;
            else
                app.OutputMsg.FontColor = "r";
                app.OutputMsg.Value = "No points found at selected location";
            end
        end

        function clearMap(app)
            app.CurrObjectInd = [];
            app.CurrObjectROI = [];
            app.ListedName = '';
            pcScatter = app.UIAxes.Children(end);
            pcScatter.CData = app.pcColor;
            delete(app.CuboidHandle);
        end

        function selectROI(app)
            app.clearMap;
            [midROI, inMap, CamDiff] = app.findObject;
            objSize = app.SizeDropDown.Value;
            app.ObjectListBox.Value = {};
            app.ObjectListBoxValueChanged;
            
            if inMap
                ROI([1, 3, 5]) = midROI + (CamDiff - app.CameraDistance) / app.CameraDistance * objSize / 2;
                ROI([2, 4, 6]) = ROI([1, 3, 5]) + objSize;

                indROI = findPointsInROI(app.pc,ROI);
                nInd = length(indROI);
                app.CurrObjectInd = indROI;
                app.CurrObjectROI = ROI;
                
                pcScatter = app.UIAxes.Children(end);
                pcScatter.CData(indROI, :) = ones(nInd, 1) .* app.SelectedColor;

                
                if isempty(app.pc.Intensity)
                    meanIntensity = NaN;
                else
                    meanIntensity = mean(app.pc.Intensity(indROI));
                end
                app.OutputMsg.FontColor = "k";
                app.OutputMsg.Value = sprintf(['ROI selected successfully! \nThe Size of the selected ROI is: ' num2str(objSize) '^3 \nThe mean intensity of this region is: ', num2str(meanIntensity)]);
                app.NavCommand = sprintf('indSubPC = findPointsInROI(app.pc,ROI);\n\nSubPC = select(app.pc, indSubPC);');
                app.logCommands;

                hold(app.UIAxes, "on");
                app.CuboidHandle = images.roi.Cuboid(app.UIAxes, 'FaceAlpha', 0, 'InteractionsAllowed', 'none', 'Color', 'r', ...
                    'Position', [app.CurrObjectROI(1:2:6), app.CurrObjectROI(2:2:6) - app.CurrObjectROI(1:2:6)]);
                hold(app.UIAxes, "off");
            else
                app.OutputMsg.FontColor = "r";
                app.OutputMsg.Value = "No points found at selected location";
            end

        end
        
        function classifyObject(app)
            if ~isempty(app.CurrObjectInd)
                
                if isempty(app.VoxNet)
                    netStruct = load('pCloudNN.mat');
                    app.VoxNet = netStruct.voxnet;
                end
                
                occupancyGrid = app.formOccupancyGrid;
                app.ListedName = char(classify(app.VoxNet, occupancyGrid));
                app.CuboidHandle.Label = app.ListedName;
                
                app.OutputMsg.FontColor = "k";
                app.OutputMsg.Value = "Object classified successfully!";
            else
                app.OutputMsg.FontColor = "r";
                app.OutputMsg.Value = "No object selected";
            end
        end

        function occupancyGrid = formOccupancyGrid(app)
            pCloud = select(app.pc,app.CurrObjectInd);
            grid = pcbin(pCloud,[32 32 32]);
            occupancyGrid = zeros(size(grid),'single');
            for ii = 1:numel(grid)
                occupancyGrid(ii) = ~isempty(grid{ii});
            end
        end

        function saveObject(app)
            if ~isempty(app.CurrObjectInd)
                app.ObjectNumTot = app.ObjectNumTot + 1;
                app.ObjectNameCount = app.ObjectNameCount + 1;
                app.ObjectROICell{app.ObjectNumTot} = app.CurrObjectROI;
                app.ObjectIndCell{app.ObjectNumTot} = app.CurrObjectInd;
                app.ObjectListBox.ItemsData(app.ObjectNumTot) = app.ObjectNumTot;
                
                if isempty(app.ListedName)
                    app.ListedName = ['Object_' num2str(app.ObjectNameCount)];
                else
                    app.ListedName = [app.ListedName '_' num2str(app.ObjectNameCount)];
                end

                app.ObjectListBox.Items{app.ObjectNumTot} = app.ListedName;
                app.ObjectListBox.Value = app.ObjectNumTot;
                app.ObjectListBoxValueChanged;

                app.OutputMsg.FontColor = "k";
                app.OutputMsg.Value = "Object saved successfully!";
            else
                app.OutputMsg.FontColor = "r";
                app.OutputMsg.Value = "No object selected";
            end
        end

        function deleteObject(app)
            app.ObjectNumTot = app.ObjectNumTot - length(app.ObjectListBox.Value);
            app.ObjectROICell(app.ObjectListBox.Value) = [];
            app.ObjectIndCell(app.ObjectListBox.Value) = [];
            app.ObjectListBox.Items(app.ObjectListBox.Value) = [];
            app.ObjectListBox.ItemsData = 1 : app.ObjectNumTot;
            
            app.OutputMsg.FontColor = "k";
            app.OutputMsg.Value = "Object deleted successfully!";
            app.ObjectListBox.Value = {};
            app.ObjectListBoxValueChanged;
        end
       
        function exportObject(app)
            Objects= app.createObjectStruct; 
            [file,path] = uiputfile('*.mat','Save File As','ExtractedObjects.mat');
            if file
                filename = fullfile(path,file);
                save(filename, 'Objects');
                app.OutputMsg.FontColor = "k";
                app.OutputMsg.Value = "Object exported successfully!";
                app.ObjectListBox.Value = {};
                app.ObjectListBoxValueChanged;
            end
        end
        
        function openAnalysisApp(app)
            if exist('AnalyzePointCloud.mlapp', 'file')
                Objects = app.createObjectStruct;
                AnalyzePointCloud(Objects);
                app.OutputMsg.FontColor = "k";
                app.OutputMsg.Value = "AnalyzePointCloud App opened successfully!";
                app.ObjectListBox.Value = {};
                app.ObjectListBoxValueChanged;
            else
                app.OutputMsg.FontColor = "r";
                app.OutputMsg.Value = "AnalyzePointCloud NOT on the current path";
            end
        end

        function [Objects] = createObjectStruct(app)
            nObjects = length(app.ObjectListBox.Value);
            ObjectCell = cell(1,nObjects);
            for i = 1:nObjects
                ObjectCell{i} = select(app.pc, app.ObjectIndCell{app.ObjectListBox.Value(i)});
            end
            ObjectRegionCell = app.ObjectROICell(app.ObjectListBox.Value);
            Objects.ObjectCell = ObjectCell;
            Objects.ObjectRegionCell = ObjectRegionCell;
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, pc)
            if exist('pc', 'var')
                app.setPointCloudParameters(pc);
                app.setRepositionParameters;
                app.setObjectParameters;
                app.setTimer;
                app.displayAxes;
            else
                disp("You need to input a point cloud obect when running this app")
                app.ExplorePointCloudUIFigureCloseRequest;
            end
        end

        % Value changed function: BackgroundColorDropDown
        function BackgroundColorDropDownValueChanged(app, event)
            app.pcHandleMap.Color = app.BackgroundColorDropDown.Value;
            app.GridLayout1.BackgroundColor = app.BackgroundColorDropDown.Value;
            app.NavCommand = "app.pcHandle.Color = " + app.BackgroundColorDropDown.Value + ";";
            app.logCommands
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            if app.XEditField.Value >= app.MapXLim(1) && app.XEditField.Value <= app.MapXLim(2)
                app.XEditField.FontColor = "k";
                app.OutputMsg.Value = "";
            else
                app.XEditField.FontColor = "r";
                app.OutputMsg.FontColor = "r";
                app.OutputMsg.Value = "X position not in map range";
            end
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            if app.YEditField.Value >= app.MapYLim(1) && app.YEditField.Value <= app.MapYLim(2)
                app.YEditField.FontColor = "k";
                app.OutputMsg.Value = "";
            else
                app.YEditField.FontColor = "r";
                app.OutputMsg.FontColor = "r";
                app.OutputMsg.Value = "Y position not in map range";
            end
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            if app.ZEditField.Value >= app.MapZLim(1) && app.ZEditField.Value <= app.MapZLim(2)
                app.ZEditField.FontColor = "k";
                app.OutputMsg.Value = "";
            else
                app.ZEditField.FontColor = "r";
                app.OutputMsg.FontColor = "r";
                app.OutputMsg.Value = "Z position not in map range";
            end
        end

        % Button pushed function: RepositionButton
        function RepositionButtonPushed(app, event)
            isLocationValid = all(app.XEditField.FontColor == [0 0 0]) && all(app.YEditField.FontColor == [0 0 0]) && all(app.ZEditField.FontColor == [0 0 0]);
            if isLocationValid
                app.OutputMsg.FontColor = "k";
                app.OutputMsg.Value = "Position changed successfully!";
                app.changePosition;
            else
                app.OutputMsg.FontColor = "r";
                app.OutputMsg.Value = "Chosen location is not valid";
            end
        end

        % Value changed function: ObjectListBox
        function ObjectListBoxValueChanged(app, event)
            app.clearMap;
            if isempty(app.ObjectListBox.Value)
                app.DeleteSelectedButton.Enable = "off";
                app.ExportSelectedButton.Enable = "off";
                app.LaunchAnalyzePointCloudButton.Enable = "off";
            else
                for ind = 1:length(app.ObjectListBox.Value)
                    ObjectNum = app.ObjectListBox.Value(ind);
                    ObjectInd = app.ObjectIndCell{ObjectNum};
                    nInd = length(ObjectInd);
                    pcScatter = app.UIAxes.Children(end);
                    pcScatter.CData(ObjectInd, :) = ones(nInd, 1) .* app.SavedColor;
                    
                    ObjectROI = app.ObjectROICell{ObjectNum};
                    app.CuboidHandle(ind) = images.roi.Cuboid(app.UIAxes, 'FaceAlpha', 0, 'InteractionsAllowed', 'none', 'Color', 'g', ...
                    'Position', [ObjectROI(1:2:6), ObjectROI(2:2:6) - ObjectROI(1:2:6)], 'Label', app.ObjectListBox.Items{ObjectNum});
                end
                app.DeleteSelectedButton.Enable = "on";
                app.ExportSelectedButton.Enable = "on";
                app.LaunchAnalyzePointCloudButton.Enable = "on";
            end
        end

        % Button pushed function: DeleteSelectedButton
        function DeleteSelectedButtonPushed(app, event)
            app.deleteObject;
        end

        % Button pushed function: ExportSelectedButton
        function ExportSelectedButtonPushed(app, event)
            app.exportObject;
        end

        % Button pushed function: LaunchAnalyzePointCloudButton
        function LaunchAnalyzePointCloudButtonPushed(app, event)
            app.openAnalysisApp;
        end

        % Window key press function: ExplorePointCloudUIFigure
        function ExplorePointCloudUIFigureWindowKeyPress(app, event)
            key = event.Key;
                switch key
                    case 'capslock'
                        if strcmp(app.Timer.Running,'off')
                            app.enableCursor;
                        else
                            app.disableCursor;
                        end
                    case 'a'
                        camdolly(app.UIAxes, -app.CamDollySlider.Value, 0, 0);
                        app.NavCommand = "camdolly(app.UIAxes, -" + num2str(app.CamDollySlider.Value) + ", 0, 0);";
                    case 'd'
                        camdolly(app.UIAxes, app.CamDollySlider.Value, 0, 0);
                        app.NavCommand = "camdolly(app.UIAxes, " + num2str(app.CamDollySlider.Value) + ", 0, 0);";
                    case 'w'
                        camdolly(app.UIAxes, 0, 0, app.CamDollySlider.Value);
                        app.NavCommand = "camdolly(app.UIAxes, 0, 0, " + num2str(app.CamDollySlider.Value) + ");";
                    case 's'
                        camdolly(app.UIAxes, 0, 0, -app.CamDollySlider.Value);
                        app.NavCommand = "camdolly(app.UIAxes, 0, 0, -" + num2str(app.CamDollySlider.Value) + ");";
                    case 'r'
                        camdolly(app.UIAxes, 0, app.CamDollySlider.Value, 0);
                        app.NavCommand = "camdolly(app.UIAxes, 0, " + num2str(app.CamDollySlider.Value) + ", 0);";
                    case 'f'
                        camdolly(app.UIAxes, 0, -app.CamDollySlider.Value, 0);
                        app.NavCommand = "camdolly(app.UIAxes, 0, -" + num2str(app.CamDollySlider.Value) + ", 0);";
                    case 'uparrow'
                        if (length(event.Modifier) == 1) && strcmp(event.Modifier{1}, 'shift')
                            objSize = app.SizeDropDown.Value;
                            CamTar = app.UIAxes.CameraTarget;
                            CamPos = app.UIAxes.CameraPosition;
                            CamTar = CamPos + (CamTar - CamPos) * objSize / app.CameraDistance;
                            camtarget(app.UIAxes, CamTar);
                            camorbit(app.UIAxes, 0,app.CamPanSlider.Value);
                            CamPos = app.UIAxes.CameraPosition;
                            CamTar = CamPos + (CamTar - CamPos) / objSize * app.CameraDistance;
                            camtarget(app.UIAxes, CamTar);
                            app.NavCommand = "camorbit(app.UIAxes, 0, " + num2str(app.CamPanSlider.Value) + ");";
                        else
                            campan(app.UIAxes, 0,app.CamPanSlider.Value);
                            app.NavCommand = "campan(app.UIAxes, 0, " + num2str(app.CamPanSlider.Value) + ");";
                        end
                    case 'downarrow'
                        if (length(event.Modifier) == 1) && strcmp(event.Modifier{1}, 'shift')
                            objSize = app.SizeDropDown.Value;
                            CamTar = app.UIAxes.CameraTarget;
                            CamPos = app.UIAxes.CameraPosition;
                            CamTar = CamPos + (CamTar - CamPos) * objSize / app.CameraDistance;
                            camtarget(app.UIAxes, CamTar);
                            camorbit(app.UIAxes, 0,-app.CamPanSlider.Value);
                            CamPos = app.UIAxes.CameraPosition;
                            CamTar = CamPos + (CamTar - CamPos) / objSize * app.CameraDistance;
                            camtarget(app.UIAxes, CamTar);
                            app.NavCommand = "camorbit(app.UIAxes, 0, -" + num2str(app.CamPanSlider.Value) + ");";
                        else
                            campan(app.UIAxes, 0,-app.CamPanSlider.Value);
                            app.NavCommand = "campan(app.UIAxes, 0, -" + num2str(app.CamPanSlider.Value) + ");";
                        end
                    case 'leftarrow'
                        if (length(event.Modifier) == 1) && strcmp(event.Modifier{1}, 'shift')
                            objSize = app.SizeDropDown.Value;
                            CamTar = app.UIAxes.CameraTarget;
                            CamPos = app.UIAxes.CameraPosition;
                            CamTar = CamPos + (CamTar - CamPos) * objSize / app.CameraDistance;
                            camtarget(app.UIAxes, CamTar);
                            camorbit(app.UIAxes, -app.CamPanSlider.Value,0);
                            CamPos = app.UIAxes.CameraPosition;
                            CamTar = CamPos + (CamTar - CamPos) / objSize * app.CameraDistance;
                            camtarget(app.UIAxes, CamTar);
                            app.NavCommand = "camorbit(app.UIAxes, -" + num2str(app.CamPanSlider.Value) + ", 0);";
                        else
                            campan(app.UIAxes, -app.CamPanSlider.Value,0);
                            app.NavCommand = "campan(app.UIAxes, -" + num2str(app.CamPanSlider.Value) + ", 0);";
                        end
                    case 'rightarrow'
                        if (length(event.Modifier) == 1) && strcmp(event.Modifier{1}, 'shift')
                            objSize = app.SizeDropDown.Value;
                            CamTar = app.UIAxes.CameraTarget;
                            CamPos = app.UIAxes.CameraPosition;
                            CamTar = CamPos + (CamTar - CamPos) * objSize / app.CameraDistance;
                            camtarget(app.UIAxes, CamTar);
                            camorbit(app.UIAxes, app.CamPanSlider.Value,0);
                            CamPos = app.UIAxes.CameraPosition;
                            CamTar = CamPos + (CamTar - CamPos) / objSize * app.CameraDistance;
                            camtarget(app.UIAxes, CamTar);
                            app.NavCommand = "camorbit(app.UIAxes, " + num2str(app.CamPanSlider.Value) + ", 0);";
                        else
                            campan(app.UIAxes, app.CamPanSlider.Value,0);
                            app.NavCommand = "campan(app.UIAxes, " + num2str(app.CamPanSlider.Value) + ", 0);";
                        end
                    case 'z'
                        camzoom(app.UIAxes, 1 /(1 + app.CamZoomSlider.Value));
                        app.NavCommand = "camzoom(app.UIAxes, " + num2str(1 /(1 + app.CamZoomSlider.Value)) + ");";
                    case 'x'
                        camzoom(app.UIAxes, 1 + app.CamZoomSlider.Value);
                        app.NavCommand = "camzoom(app.UIAxes, " + num2str(1 + app.CamZoomSlider.Value) + ");";
                    case 'q'
                        camroll(app.UIAxes, app.CamRollSlider.Value);
                        app.NavCommand = "camroll(app.UIAxes, " + num2str(app.CamRollSlider.Value) + ");";
                    case 'e'
                        camroll(app.UIAxes, -app.CamRollSlider.Value);
                        app.NavCommand = "camroll(app.UIAxes, -" + num2str(app.CamRollSlider.Value) + ");";
                    case 'c'
                        app.selectROI;
                    case 'v'
                        app.saveObject;
                        app.NavCommand = "";
                    case 'b'
                        app.clearMap;
                        app.ObjectListBox.Value = {};
                        app.NavCommand = "";
                    case 'n'
                        app.moveToObject;
                    case 'm'
                        app.classifyObject;
                end
                
                app.logCommands;
                % Update current location displayed and scope location
                app.setScopeLocation;
                app.setCurrentLocation;
        end

        % Window scroll wheel function: ExplorePointCloudUIFigure
        function ExplorePointCloudUIFigureWindowScrollWheel(app, event)
            if strcmp(app.Timer.Running,'on')
                camzoom(app.UIAxes, (1 + app.CamZoomSlider.Value)^event.VerticalScrollCount); % Possibly invert scroll direction
            end   
        end

        % Window button down function: ExplorePointCloudUIFigure
        function ExplorePointCloudUIFigureWindowButtonDown(app, event)
            if strcmp(app.Timer.Running,'on')
                clickType = app.ExplorePointCloudUIFigure.SelectionType;
                if strcmp(clickType, 'extend')
                    app.moveToObject;
                elseif strcmp(clickType, 'normal')
                    app.selectROI;
                elseif strcmp(clickType, 'alt')
                    app.saveObject;
                end
            end
        end

        % Close request function: ExplorePointCloudUIFigure
        function ExplorePointCloudUIFigureCloseRequest(app, event)
            if exist('app.Timer', 'var')
                stop(app.Timer);
                delete(app.Timer);
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ExplorePointCloudUIFigure = uifigure('Visible', 'off');
            app.ExplorePointCloudUIFigure.Name = 'Point Cloud Navigate And Extract App';
            app.ExplorePointCloudUIFigure.CloseRequestFcn = createCallbackFcn(app, @ExplorePointCloudUIFigureCloseRequest, true);
            app.ExplorePointCloudUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @ExplorePointCloudUIFigureWindowButtonDown, true);
            app.ExplorePointCloudUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @ExplorePointCloudUIFigureWindowScrollWheel, true);
            app.ExplorePointCloudUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @ExplorePointCloudUIFigureWindowKeyPress, true);
            app.GridLayout1 = uigridlayout(app.ExplorePointCloudUIFigure);
            app.UIAxes = uiaxes(app.GridLayout1);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.BottomTabGroup = uitabgroup(app.GridLayout1);
            app.ControlsTab = uitab(app.BottomTabGroup);
            app.ControlsTab.Title = 'Controls';
            app.GridLayout2 = uigridlayout(app.ControlsTab);
            app.PointCloudInfoPanel = uipanel(app.GridLayout2);
            app.PointCloudInfoPanel.Title = 'Point Cloud Info ';
            app.MapLimitsLabel = uilabel(app.PointCloudInfoPanel);
            app.MapLimitsLabel.Text = 'Map Limits';
            app.MapLimitsTextArea = uitextarea(app.PointCloudInfoPanel);
            app.MapLimitsTextArea.Editable = 'off';
            app.TotalPointCountEditFieldLabel = uilabel(app.PointCloudInfoPanel);
            app.TotalPointCountEditFieldLabel.Text = 'Total Point Count';
            app.TotalPointCountEditField = uieditfield(app.PointCloudInfoPanel, 'numeric');
            app.TotalPointCountEditField.Editable = 'off';
            app.CurrentLocationLabel = uilabel(app.PointCloudInfoPanel);
            app.CurrentLocationLabel.Text = 'Current Location';
            app.CurrentLocationTextArea = uitextarea(app.PointCloudInfoPanel);
            app.CurrentLocationTextArea.Editable = 'off';
            app.BackgroundColorDropDownLabel = uilabel(app.PointCloudInfoPanel);
            app.BackgroundColorDropDownLabel.Text = 'Background Color';
            app.BackgroundColorDropDown = uidropdown(app.PointCloudInfoPanel);
            app.BackgroundColorDropDown.Items = {'cyan', 'white', 'black', 'blue', 'red', 'green', 'yellow', 'magenta'};
            app.BackgroundColorDropDown.ValueChangedFcn = createCallbackFcn(app, @BackgroundColorDropDownValueChanged, true);
            app.BackgroundColorDropDown.Value = 'cyan';
            app.LogCommandsLabel = uilabel(app.PointCloudInfoPanel);
            app.LogCommandsLabel.Text = 'Log Commands';
            app.LogCheckBox = uicheckbox(app.PointCloudInfoPanel);
            app.LogCheckBox.Text = '';
            app.OutputMessagePanel = uipanel(app.GridLayout2);
            app.OutputMessagePanel.Title = 'Output Message';
            app.OutputMsg = uitextarea(app.OutputMessagePanel);
            app.OutputMsg.Editable = 'off';
            app.LaunchAnalyzePointCloudButton = uibutton(app.OutputMessagePanel, 'push');
            app.LaunchAnalyzePointCloudButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchAnalyzePointCloudButtonPushed, true);
            app.LaunchAnalyzePointCloudButton.Enable = 'off';
            app.LaunchAnalyzePointCloudButton.Text = 'Launch AnalyzePointCloud';
            app.NavigationParametersPanel = uipanel(app.GridLayout2);
            app.NavigationParametersPanel.Title = 'Navigation Parameters';
            app.CamDollySliderLabel = uilabel(app.NavigationParametersPanel);
            app.CamDollySliderLabel.Text = 'CamDolly';
            app.CamDollySlider = uislider(app.NavigationParametersPanel);
            app.CamDollySlider.Limits = [0 10];
            app.CamDollySlider.MinorTicks = [1 3 5 7 9];
            app.CamDollySlider.Tooltip = {'Set the ''dx/dy/dz'' input of the "camdolly" function to change how fast you move around the point cloud'};
            app.CamDollySlider.Value = 1;
            app.ZoomFactorLabel = uilabel(app.NavigationParametersPanel);
            app.ZoomFactorLabel.Tooltip = {'Set the ''zoom_factor'' input of the "camzoom" function to change how much to zoom in/out the point cloud'};
            app.ZoomFactorLabel.Text = 'CamZoom';
            app.CamZoomSlider = uislider(app.NavigationParametersPanel);
            app.CamZoomSlider.Limits = [0 1];
            app.CamZoomSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.CamZoomSlider.MinorTicks = [];
            app.CamZoomSlider.Tooltip = {'Set the ''dx/dy/dz'' input of the "camdolly" function to change how fast you move around '};
            app.CamZoomSlider.Value = 0.2;
            app.CamPanSliderLabel = uilabel(app.NavigationParametersPanel);
            app.CamPanSliderLabel.Text = 'CamPan';
            app.CamPanSlider = uislider(app.NavigationParametersPanel);
            app.CamPanSlider.Limits = [0 90];
            app.CamPanSlider.MajorTicks = [0 15 30 45 60 75 90];
            app.CamPanSlider.MinorTicks = [15 45 75];
            app.CamPanSlider.Tooltip = {'Set the ''dtheta/dphi'' input of the "campan" function to change how fast you look around the point cloud'};
            app.CamPanSlider.Value = 15;
            app.CamRollSliderLabel = uilabel(app.NavigationParametersPanel);
            app.CamRollSliderLabel.Text = 'CamRoll';
            app.CamRollSlider = uislider(app.NavigationParametersPanel);
            app.CamRollSlider.Limits = [0 90];
            app.CamRollSlider.MajorTicks = [0 15 30 45 60 75 90];
            app.CamRollSlider.MinorTicks = [15 45 75];
            app.CamRollSlider.Tooltip = {'Set the ''dtheta'' input of the "camroll" function to change how fast you rotate pointcloud '};
            app.CamRollSlider.Value = 15;
            app.EgoPositionPanel = uipanel(app.GridLayout2);
            app.EgoPositionPanel.Title = 'Ego Position';
            app.RepositionButton = uibutton(app.EgoPositionPanel, 'push');
            app.RepositionButton.ButtonPushedFcn = createCallbackFcn(app, @RepositionButtonPushed, true);
            app.RepositionButton.Text = 'Reposition';
            app.ZEditFieldLabel = uilabel(app.EgoPositionPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.EgoPositionPanel, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.ZEditField.Tooltip = {'Set the [x,y,z] input of the "campos" function to change your location within the pointcloud'};
            app.ZEditField.Value = 1;
            app.YEditFieldLabel = uilabel(app.EgoPositionPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.EgoPositionPanel, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.YEditField.Tooltip = {'Set the [x,y,z] input of the "campos" function to change your location within the pointcloud'};
            app.YEditField.Value = 1;
            app.XpositionLabel = uilabel(app.EgoPositionPanel);
            app.XpositionLabel.Text = 'X';
            app.XEditField = uieditfield(app.EgoPositionPanel, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.XEditField.Tooltip = {'Set the [x,y,z] input of the "campos" function to change your location within the pointcloud'};
            app.XEditField.Value = 1;
            app.CamTargetDropDownLabel = uilabel(app.EgoPositionPanel);
            app.CamTargetDropDownLabel.Text = 'CamTarget';
            app.CamTargetDropDown = uidropdown(app.EgoPositionPanel);
            app.CamTargetDropDown.Items = {'YZ - Plane', 'XZ - Plane'};
            app.CamTargetDropDown.ItemsData = [1 2];
            app.CamTargetDropDown.Tooltip = {'Set the [x,y,z] input of the "camtarget" function to change the target location within the pointcloud (either look along the X or Y direction)'};
            app.CamTargetDropDown.Value = 1;
            app.CamPosLabel = uilabel(app.EgoPositionPanel);
            app.CamPosLabel.Tooltip = {'Set the [x,y,z] input of the "campos" function to change your location within the pointcloud'};
            app.CamPosLabel.Text = 'CamPos';
            app.ObjectsListPanel = uipanel(app.GridLayout2);
            app.ObjectsListPanel.Title = 'Objects List';
            app.ExportSelectedButton = uibutton(app.ObjectsListPanel, 'push');
            app.ExportSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSelectedButtonPushed, true);
            app.ExportSelectedButton.Enable = 'off';
            app.ExportSelectedButton.Text = 'Export Selected';
            app.SizeDropDownLabel = uilabel(app.ObjectsListPanel);
            app.SizeDropDownLabel.Text = 'Size';
            app.SizeDropDown = uidropdown(app.ObjectsListPanel);
            app.SizeDropDown.Items = {'Small', 'Medium', 'Large'};
            app.SizeDropDown.Value = 'Medium';
            app.ObjectListBox = uilistbox(app.ObjectsListPanel);
            app.ObjectListBox.Items = {};
            app.ObjectListBox.Multiselect = 'on';
            app.ObjectListBox.ValueChangedFcn = createCallbackFcn(app, @ObjectListBoxValueChanged, true);
            app.ObjectListBox.Value = {};
            app.DeleteSelectedButton = uibutton(app.ObjectsListPanel, 'push');
            app.DeleteSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedButtonPushed, true);
            app.DeleteSelectedButton.Enable = 'off';
            app.DeleteSelectedButton.Text = 'Delete Selected';
            app.InstructionsTab = uitab(app.BottomTabGroup);
            app.InstructionsTab.Title = 'Instructions';
            app.GridLayout3 = uigridlayout(app.InstructionsTab);
            app.InstructionImage = uiimage(app.GridLayout3);
            app.InstructionImage.ImageSource = fullfile(pathToMLAPP, 'ExplorePointCloud_Instructions.png');
            % Show the figure after all components are created
            app.ExplorePointCloudUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExplorePointCloud(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExplorePointCloudUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExplorePointCloudUIFigure)
        end
    end
end