classdef PointCloudFitAndClusterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PointCloudFitAndClusterAppUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        ProcessingPanel                matlab.ui.container.Panel
        OutputMessagePanel             matlab.ui.container.Panel
        ErrorMsg                       matlab.ui.control.TextArea
        pcFitShapePanel                matlab.ui.container.Panel
        ModelInfoTextArea              matlab.ui.control.TextArea
        ModelInfoTextAreaLabel         matlab.ui.control.Label
        MaxDistanceEditField           matlab.ui.control.NumericEditField
        MaxDistanceEditFieldLabel      matlab.ui.control.Label
        ReferenceVectorDropDown        matlab.ui.control.DropDown
        ReferenceVectorDropDownLabel   matlab.ui.control.Label
        FitShapeButton                 matlab.ui.control.Button
        ShapeDropDown                  matlab.ui.control.DropDown
        ShapeDropDownLabel             matlab.ui.control.Label
        pcSegDistPanel                 matlab.ui.container.Panel
        ClusterDistanceEditField       matlab.ui.control.NumericEditField
        ClusterDistanceEditFieldLabel  matlab.ui.control.Label
        ClusterButton                  matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        DeleteShapeButton              matlab.ui.control.Button
        ShapeDisplayButtonGroup        matlab.ui.container.ButtonGroup
        OutlierPointsButton            matlab.ui.control.RadioButton
        InlierPointsButton             matlab.ui.control.RadioButton
        ModelButton                    matlab.ui.control.RadioButton
        ObjectsPanel                   matlab.ui.container.Panel
        LogCommandsLabel               matlab.ui.control.Label
        LogCheckBox                    matlab.ui.control.CheckBox
        BackgroundColorDropDown        matlab.ui.control.DropDown
        BackgroundColorDropDownLabel   matlab.ui.control.Label
        ScriptFileNameEditField        matlab.ui.control.EditField
        ScriptFileNameEditFieldLabel   matlab.ui.control.Label
        ExportCodeButton               matlab.ui.control.Button
        ExportObjectButton             matlab.ui.control.Button
        ObjectFileNameEditField        matlab.ui.control.EditField
        ObjectFileNameEditFieldLabel   matlab.ui.control.Label
        DeleteObjectButton             matlab.ui.control.Button
        ObjectInfoTextArea             matlab.ui.control.TextArea
        ObjectInfoTextAreaLabel        matlab.ui.control.Label
        ListofObjectsListBox           matlab.ui.control.ListBox
        ListofObjectsListBoxLabel      matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %% Fix issue with empty objects: Error using  * Incorrect dimensions for matrix multiplication.
        %% Disable save when showing the model of fitted shape


        % Axes Properties
        AxesBGColor % Background of axes where pCloud is displayed
        pcHandleObject % Handle to object pcshow 

        % Logging Properties
        LogCommand = "";% Last navigation command executed

        
        % Object Properties
        ObjectCell = {};
        ObjectROICell = {};
        ObjectCodeCell = {};
        ObjectNumTot = 0;
        ObjectNameCount = 0;

        % Shape Properties
        ShapeHandle;
        ShapeRNG;
        ShapeInlierIndices;
        ShapeOutlierIndeces;
        ShapeInPC;
        ShapeOutPC;
        ShapeModel;
        ShapeInName;
        ShapeOutName;
        ShapeInCode;
        ShapeOutCode;
    end
    
    methods (Access = private)
        
        % Set Up Functions
        function loadObjects(app,Objects)
            app.ObjectCell = Objects.ObjectCell;
            app.ObjectROICell = Objects.ObjectRegionCell;
            app.ObjectNumTot = length(app.ObjectCell);
            app.ObjectNameCount = app.ObjectNumTot;
        end
        
      
        function populateList(app)
            for i = 1:app.ObjectNumTot
                app.ObjectCodeCell{i} = sprintf(['IndecesROI = findPointsInROI(pointCloud, [' num2str(app.ObjectROICell{i}) ']);\nSubPC = select(pointCloud, IndecesROI);']);
                app.ListofObjectsListBox.Items{i} = ['Object_' num2str(i)];
                 app.ListofObjectsListBox.ItemsData(i) = i;     
                
            end
            app.ListofObjectsListBox.Value = 1;
            app.ListofObjectsListBoxValueChanged;
        end


        function logCommands(app)
            if app.LogCheckBox.Value
                app.ErrorMsg.Value = app.LogCommand;
                app.ErrorMsg.FontColor = "k";
                app.LogCommand = "";
            end
        end

        
        % Object Functions
        function displaySelectedObject(app)
            objectNum = app.ListofObjectsListBox.Value;
            ObjectPc = app.ObjectCell{objectNum};
            objectName = app.ListofObjectsListBox.Items{objectNum};
            app.pcHandleObject = pcshow(ObjectPc, "Parent", app.UIAxes);
            rotate3d(app.UIAxes, 'off');
            app.pcHandleObject.Visible = 'on';
            app.pcHandleObject.Color = app.BackgroundColorDropDown.Value;
            [XLim, YLim, ZLim, Length, Width, Height, Volume, nPoints, Density ] = app.calculateObjectMeasurements(ObjectPc);
            app.ObjectInfoTextArea.Value = sprintf(['\nObject Name:  ' objectName ...
                '\n\nLength =  ' num2str(Length) ... 
                '\nWidth =  ' num2str(Width) ...
                '\nHeight =  ' num2str(Height) ...
                '\n\nVolume =  ' num2str(Volume) ... 
                '\nPoint Count =  ' num2str(nPoints) ...
                '\nDensity =  ' num2str(Density) ...
                '\n\nX Limits:  [' num2str(XLim) ']'...
                '\nY Limits:  [' num2str(YLim) ']'...
                '\nZ Limits:  [' num2str(ZLim) ']']);
            
            app.deleteShape;
            app.ShapeRNG = 0;
            app.DeleteObjectButton.Enable = "on";
            app.ExportObjectButton.Enable = "on";
            app.ExportCodeButton.Enable = "on";
            app.ObjectFileNameEditField.Value = objectName;
            app.ScriptFileNameEditField.Value = ['get' objectName];
            app.ObjectFileNameEditField.Enable = "on";
            app.ScriptFileNameEditField.Enable = "on";
            app.FitShapeButton.Enable = "on";
            app.ClusterButton.Enable = "on";
        end

        function [XLim, YLim, ZLim, Length, Width, Height, Volume, nPoints, Density] = calculateObjectMeasurements(~, ObjectPc)
            XLim = round(ObjectPc.XLimits,2);
            YLim = round(ObjectPc.YLimits,2);
            ZLim = round(ObjectPc.ZLimits,2);
            Length = round(diff(XLim),2);
            Width = round(diff(YLim),2);
            Height = round(diff(ZLim),2);
            Volume = round(Length * Width * Height,2);
            nPoints = ObjectPc.Count;
            Density = round(nPoints / Volume,2);
        end


        function deleteSelectedObject(app)
                objectNum = app.ListofObjectsListBox.Value;
                app.ObjectNumTot = app.ObjectNumTot - 1;
                app.ObjectCell(objectNum) = [];
                app.ObjectCodeCell(objectNum) = [];
                app.ObjectROICell(objectNum) = [];

                app.ListofObjectsListBox.ItemsData= 1 : app.ObjectNumTot;
                app.ListofObjectsListBox.Items(objectNum) = [];
                app.ListofObjectsListBox.Value = {};
                app.ObjectInfoTextArea.Value = '';
                
                app.deleteShape;
                
                app.DeleteObjectButton.Enable = "off";
                app.ObjectFileNameEditField.Value = '';
                app.ScriptFileNameEditField.Value = '';
                app.ExportObjectButton.Enable = "off";
                app.ExportCodeButton.Enable = "off";
                app.ObjectFileNameEditField.Enable = "off";
                app.ScriptFileNameEditField.Enable = "off";
                app.FitShapeButton.Enable = "off";
                app.ClusterButton.Enable = "off";
                app.ShapeRNG = 0;
                
                prevObjNum = objectNum - 1;
                if prevObjNum== 0
                    if app.ObjectNumTot == 0
                        app.pcHandleObject = pcshow([1 1 1], "Parent", app.UIAxes);
                        rotate3d(app.UIAxes, 'off');
                        app.pcHandleObject.Visible = 'on';
                        app.pcHandleObject.Color = app.BackgroundColorDropDown.Value;
                    else
                        prevObjNum = 1;
                        app.ListofObjectsListBox.Value = prevObjNum;
                        app.displaySelectedObject;
                    end
                else
                    app.ListofObjectsListBox.Value = prevObjNum;
                    app.displaySelectedObject;
                end
        end

        function exportSelectedObject(app)
                objectNum = app.ListofObjectsListBox.Value;
                ObjectPc = app.ObjectCell{objectNum};
                [file,path] = uiputfile('*.mat','Save File As', sprintf('%s.mat', app.ObjectFileNameEditField.Value));
                if file
                    filename = fullfile(path,file);
                    save(filename, "ObjectPc")
                    app.ObjectFileNameEditField.Value = '';
                    app.ErrorMsg.FontColor = "k";
                    app.ErrorMsg.Value = "Object exported successfully!";
                    app.ExportObjectButton.Enable = "off";
                    app.ObjectFileNameEditField.Enable = "off";
                end
        end

        function exportSelectedObjectCode(app)
                objectNum = app.ListofObjectsListBox.Value;
                ObjectCode = app.ObjectCodeCell{objectNum};
                [file,path] = uiputfile('*.m','Save File As',sprintf('%s.m', app.ScriptFileNameEditField.Value));
                functionCode = sprintf(['function SubPC = ' file(1:end-2) '(pointCloud)\n' ObjectCode '\nend']);
                if file
                    scriptname = fullfile(path,file);
                    fid = fopen(scriptname, 'wt');
    
                    fprintf(fid, functionCode);
                    fclose(fid);
                    edit(scriptname)
    
                    app.ScriptFileNameEditField.Value = '';
                    app.ErrorMsg.FontColor = "k";
                    app.ErrorMsg.Value = "Script exported successfully!";
                    app.ExportCodeButton.Enable = "off";
                    app.ScriptFileNameEditField.Enable = "off";
                end
        end
        
        % Shape Functions
        function fitShapeToSelectedObject(app)
            app.deleteShape;
            objectNum = app.ListofObjectsListBox.Value;
            ObjectPc = app.ObjectCell{objectNum};
            ObjectCode = app.ObjectCodeCell{objectNum};
            app.ShapeRNG = app.ShapeRNG + 1;
            rng(app.ShapeRNG);
            ShapeCode = sprintf([ObjectCode '\nrng(' num2str(app.ShapeRNG) ');']);

            maxDistance = app.MaxDistanceEditField.Value;

            if app.ReferenceVectorDropDown.Value == 2
                referenceVector = [1 0 0];
            elseif app.ReferenceVectorDropDown.Value == 3
                referenceVector = [0 1 0];
            elseif app.ReferenceVectorDropDown.Value == 4
                referenceVector = [0 0 1];
            else
                referenceVector = [];
            end
   
            if app.ShapeDropDown.Value == 1
                if referenceVector
                    [app.ShapeModel, app.ShapeInlierIndices, app.ShapeOutlierIndeces] = pcfitplane(ObjectPc,maxDistance,referenceVector);
                    app.LogCommand = sprintf(['pcfitplane(SubPC, ' num2str(maxDistance) ', [' num2str(referenceVector) ']);']);
                else
                    [app.ShapeModel, app.ShapeInlierIndices, app.ShapeOutlierIndeces] = pcfitplane(ObjectPc,maxDistance);
                    app.LogCommand = sprintf(['pcfitplane(SubPC, ' num2str(maxDistance) ');']);
                end
                app.ModelInfoTextArea.Value = sprintf(['\nShape Type = Plane \n\nNormal Vector [X,Y,Z]: \n\n[' num2str(round(app.ShapeModel.Normal,2)) ']']);
                app.ShapeInName = 'Plane';
                ShapeCode = sprintf([ShapeCode '\n[ShapeModel, ShapeInlierIndices, ShapeOutlierIndices] = ' app.LogCommand]); 
            elseif app.ShapeDropDown.Value == 2
                [app.ShapeModel, app.ShapeInlierIndices, app.ShapeOutlierIndeces] = pcfitsphere(ObjectPc,maxDistance);
                app.ModelInfoTextArea.Value = sprintf(['\nShape Type = Sphere \n\nRadius = ' num2str(round(app.ShapeModel.Radius,2)) '\n\nCenter Location [X,Y,Z]: \n\n[' num2str(round(app.ShapeModel.Center,2)) ']']);
                app.ShapeInName = 'Sphere';
                app.LogCommand = sprintf(['pcfitsphere(SubPC, ' num2str(maxDistance) ');']); 
                ShapeCode = sprintf([ShapeCode '\n[ShapeModel, ShapeInlierIndices, ShapeOutlierIndices] = ' app.LogCommand]); 
            else
                if referenceVector
                    [app.ShapeModel, app.ShapeInlierIndices, app.ShapeOutlierIndeces] = pcfitcylinder(ObjectPc,maxDistance,referenceVector);
                    app.LogCommand = sprintf(['pcfitcylinder(SubPC, ' num2str(maxDistance) ', [' num2str(referenceVector) ']);']); 
                else
                    [app.ShapeModel, app.ShapeInlierIndices, app.ShapeOutlierIndeces] = pcfitcylinder(ObjectPc,maxDistance);
                    app.LogCommand = sprintf(['pcfitcylinder(SubPC, ' num2str(maxDistance) ');']); 
                end
                app.ModelInfoTextArea.Value = sprintf(['\nShape Type = Cylinder \n\nRadius = ' num2str(round(app.ShapeModel.Radius,2)) ' \n\nHeight = ' num2str(round(app.ShapeModel.Height,2)) '\n\nCenterLocation [X,Y,Z]: \n\n[' num2str(round(app.ShapeModel.Center,2)) ']']);
                app.ShapeInName = 'Cylinder';
                ShapeCode = sprintf([ShapeCode '\n[ShapeModel, ShapeInlierIndices, ShapeOutlierIndices] = ' app.LogCommand]); 
            end
            app.ShapeInPC = select(ObjectPc, app.ShapeInlierIndices);
            app.ShapeInCode = sprintf([ShapeCode '\nSubPC = select(SubPC, ShapeInlierIndices);']);
            app.ShapeOutPC = select(ObjectPc, app.ShapeOutlierIndeces);
            app.ShapeOutCode = sprintf([ShapeCode '\nSubPC = select(SubPC, ShapeOutlierIndices);']);
            app.ShapeOutName = 'Object';
            

            app.ErrorMsg.FontColor = "k";
            app.ErrorMsg.Value = "Shape fitted successfully!";
            app.logCommands;

            app.ShapeDisplayButtonGroup.Enable = 'on';
            app.ModelButton.Enable = 'on';
            app.ModelButton.Value = true;
            
            app.plotShape;
        end
        
        function ClusterObject(app)
            app.deleteShape;
            objectNum = app.ListofObjectsListBox.Value;
            ObjectPc = app.ObjectCell{objectNum};
            ObjectCode = app.ObjectCodeCell{objectNum};
            app.ShapeRNG = app.ShapeRNG + 1;
            rng(app.ShapeRNG);
            ShapeCode = sprintf([ObjectCode '\nrng(' num2str(app.ShapeRNG) ');']);

            Labels= pcsegdist(ObjectPc, app.ClusterDistanceEditField.Value);
            MainLabel = mode(Labels);
            app.LogCommand = sprintf(['Labels= pcsegdist(SubPC, ' num2str(app.ClusterDistanceEditField.Value) ');\n\nMainLabel = mode(Labels);']);
            ShapeCode = sprintf([ShapeCode '\n' app.LogCommand]);
            app.ShapeInlierIndices = find(Labels == MainLabel);
            app.ShapeOutlierIndeces = find(Labels ~= MainLabel);
            app.ShapeInPC = select(ObjectPc, app.ShapeInlierIndices);
            app.ShapeOutPC = select(ObjectPc, app.ShapeOutlierIndeces);
            
            app.ShapeInCode = sprintf([ShapeCode '\nShapeInlierIndices = find(Labels == MainLabel);\nSubPC = select(SubPC, ShapeInlierIndices);']);
            app.ShapeOutCode = sprintf([ShapeCode '\nShapeOutlierIndeces = find(Labels ~= MainLabel);\nSubPC = select(SubPC, ShapeOutlierIndeces);']);
            app.ShapeInName = 'Cluster';
            app.ShapeOutName = 'Object';


            app.ErrorMsg.FontColor = "k";
            app.ErrorMsg.Value = "Cluster done successfully!";
            app.logCommands;

            app.ShapeDisplayButtonGroup.Enable = 'on';
            app.ModelButton.Enable = 'off';
            app.InlierPointsButton.Value = true;            

            app.plotShape;
        end

        function plotShape(app)
            delete(app.ShapeHandle);
            hold(app.UIAxes, "on");
            if app.InlierPointsButton.Value
                app.ShapeHandle = plot3(app.UIAxes, app.ShapeInPC.Location(:,1), app.ShapeInPC.Location(:,2),app.ShapeInPC.Location(:,3), "r+");
                app.SaveButton.Enable = 'on';
            elseif app.OutlierPointsButton.Value
                if app.ShapeOutPC.Count > 0
                    app.ShapeHandle = plot3(app.UIAxes, app.ShapeOutPC.Location(:,1), app.ShapeOutPC.Location(:,2),app.ShapeOutPC.Location(:,3), "r+");
                    app.SaveButton.Enable = 'on';
                else
                    app.SaveButton.Enable = 'off';
                end
            else
                app.ShapeHandle = plot(app.ShapeModel, "Parent", app.UIAxes);
                app.SaveButton.Enable = 'on';
            end
            hold(app.UIAxes, "off");
            app.DeleteShapeButton.Enable = 'on';
        end

        function deleteShape(app)
            delete(app.ShapeHandle);
            app.ShapeDisplayButtonGroup.Enable = 'off';
            app.DeleteShapeButton.Enable = 'off';
            app.SaveButton.Enable = 'off';
            app.ModelInfoTextArea.Value = '';
            app.ErrorMsg.Value = '';
            app.ShapeInlierIndices = [];
            app.ShapeOutlierIndeces = [];
            app.ShapeInPC = [];
            app.ShapeOutPC = [];
            app.ShapeInCode = [];
            app.ShapeOutCode = [];
            app.ShapeModel = [];
            app.ShapeInName = '';
            app.ShapeOutName = '';
        end
            
        function saveShape(app)
            app.ObjectNumTot = app.ObjectNumTot + 1;
            app.ObjectNameCount = app.ObjectNameCount + 1;

            if app.OutlierPointsButton.Value
                app.ObjectCell{app.ObjectNumTot} = app.ShapeOutPC;
                ListedName = [app.ShapeOutName '_' num2str(app.ObjectNameCount)];
                app.ObjectCodeCell{app.ObjectNumTot} = app.ShapeOutCode;
            else
                app.ObjectCell{app.ObjectNumTot} = app.ShapeInPC;
                ListedName = [app.ShapeInName '_' num2str(app.ObjectNameCount)];
                app.ObjectCodeCell{app.ObjectNumTot} = app.ShapeInCode;
            end
            
            
            app.ObjectROICell{app.ObjectNumTot} = [];
            app.ListofObjectsListBox.ItemsData(app.ObjectNumTot) = app.ObjectNumTot;
            app.ListofObjectsListBox.Items{app.ObjectNumTot} = ListedName;
    
            app.ErrorMsg.FontColor = "k";
            app.ErrorMsg.Value = "Shape saved successfully!";   
            app.SaveButton.Enable = 'off';
            app.ShapeRNG = 0;
            
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Objects)
            app.loadObjects(Objects);
            app.populateList;
        end

        % Value changed function: ListofObjectsListBox
        function ListofObjectsListBoxValueChanged(app, event)
            app.displaySelectedObject;
        end

        % Button pushed function: DeleteObjectButton
        function DeleteObjectButtonPushed(app, event)
            app.deleteSelectedObject;
        end

        % Button pushed function: ExportObjectButton
        function ExportObjectButtonPushed(app, event)
            app.exportSelectedObject;
        end

        % Button pushed function: ExportCodeButton
        function ExportCodeButtonPushed(app, event)
            app.exportSelectedObjectCode;
        end

        % Button pushed function: FitShapeButton
        function FitShapeButtonPushed(app, event)
            app.fitShapeToSelectedObject;
        end

        % Button pushed function: ClusterButton
        function ClusterButtonPushed(app, event)
            app.ClusterObject;
        end

        % Selection changed function: ShapeDisplayButtonGroup
        function ShapeDisplayButtonGroupSelectionChanged(app, event)
            app.plotShape;
        end

        % Button pushed function: DeleteShapeButton
        function DeleteShapeButtonPushed(app, event)
            app.deleteShape;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.saveShape;
        end

        % Value changed function: BackgroundColorDropDown
        function BackgroundColorDropDownValueChanged(app, event)
            app.pcHandleObject.Color = app.BackgroundColorDropDown.Value;
            app.LogCommand = "app.pcHandle.Color = " + app.AxesBGColor + ";";
            app.logCommands;
        end

        % Value changed function: ShapeDropDown
        function ShapeDropDownValueChanged(app, event)
            if app.ShapeDropDown.Value == 2
                app.ReferenceVectorDropDown.Enable = 'off';
            else
                app.ReferenceVectorDropDown.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PointCloudFitAndClusterAppUIFigure = uifigure('Visible', 'off');
            app.PointCloudFitAndClusterAppUIFigure.Name = 'Point Cloud Fit And Cluster App';
            app.GridLayout = uigridlayout(app.PointCloudFitAndClusterAppUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ObjectsPanel = uipanel(app.GridLayout);
            app.ObjectsPanel.Title = 'Objects';
            app.ListofObjectsListBoxLabel = uilabel(app.ObjectsPanel);
            app.ListofObjectsListBoxLabel.Text = 'List of Objects';
            app.ListofObjectsListBox = uilistbox(app.ObjectsPanel);
            app.ListofObjectsListBox.Items = {};
            app.ListofObjectsListBox.ValueChangedFcn = createCallbackFcn(app, @ListofObjectsListBoxValueChanged, true);
            app.ListofObjectsListBox.Value = {};
            app.ObjectInfoTextAreaLabel = uilabel(app.ObjectsPanel);
            app.ObjectInfoTextAreaLabel.Text = 'Object Info';
            app.ObjectInfoTextArea = uitextarea(app.ObjectsPanel);
            app.ObjectInfoTextArea.Editable = 'off';
            app.DeleteObjectButton = uibutton(app.ObjectsPanel, 'push');
            app.DeleteObjectButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteObjectButtonPushed, true);
            app.DeleteObjectButton.Enable = 'off';
            app.DeleteObjectButton.Text = 'Delete ';
            app.ObjectFileNameEditFieldLabel = uilabel(app.ObjectsPanel);
            app.ObjectFileNameEditFieldLabel.Text = 'Object File Name';
            app.ObjectFileNameEditField = uieditfield(app.ObjectsPanel, 'text');
            app.ObjectFileNameEditField.Enable = 'off';
            app.ExportObjectButton = uibutton(app.ObjectsPanel, 'push');
            app.ExportObjectButton.ButtonPushedFcn = createCallbackFcn(app, @ExportObjectButtonPushed, true);
            app.ExportObjectButton.Enable = 'off';
            app.ExportObjectButton.Text = 'Export Object';
            app.ExportCodeButton = uibutton(app.ObjectsPanel, 'push');
            app.ExportCodeButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCodeButtonPushed, true);
            app.ExportCodeButton.Enable = 'off';
            app.ExportCodeButton.Text = 'Export Code';
            app.ScriptFileNameEditFieldLabel = uilabel(app.ObjectsPanel);
            app.ScriptFileNameEditFieldLabel.Text = 'Script File Name';
            app.ScriptFileNameEditField = uieditfield(app.ObjectsPanel, 'text');
            app.ScriptFileNameEditField.Enable = 'off';
            app.BackgroundColorDropDownLabel = uilabel(app.ObjectsPanel);
            app.BackgroundColorDropDownLabel.Text = 'Background Color';
            app.BackgroundColorDropDown = uidropdown(app.ObjectsPanel);
            app.BackgroundColorDropDown.Items = {'cyan', 'white', 'black', 'blue', 'red', 'green', 'yellow', 'magenta'};
            app.BackgroundColorDropDown.ValueChangedFcn = createCallbackFcn(app, @BackgroundColorDropDownValueChanged, true);
            app.BackgroundColorDropDown.Value = 'cyan';
            app.LogCheckBox = uicheckbox(app.ObjectsPanel);
            app.LogCheckBox.Text = '';
            app.LogCommandsLabel = uilabel(app.ObjectsPanel);
            app.LogCommandsLabel.Text = 'Log Commands';
            app.ProcessingPanel = uipanel(app.GridLayout);
            app.ProcessingPanel.Title = 'Processing';
            app.ShapeDisplayButtonGroup = uibuttongroup(app.ProcessingPanel);
            app.ShapeDisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShapeDisplayButtonGroupSelectionChanged, true);
            app.ShapeDisplayButtonGroup.Enable = 'off';
            app.ShapeDisplayButtonGroup.Title = 'Shape Display';
            app.ModelButton = uiradiobutton(app.ShapeDisplayButtonGroup);
            app.ModelButton.Text = 'Model';
            app.InlierPointsButton = uiradiobutton(app.ShapeDisplayButtonGroup);
            app.InlierPointsButton.Text = 'Inlier Points';
            app.InlierPointsButton.Value = true;
            app.OutlierPointsButton = uiradiobutton(app.ShapeDisplayButtonGroup);
            app.OutlierPointsButton.Text = 'Outlier Points';
            app.DeleteShapeButton = uibutton(app.ProcessingPanel, 'push');
            app.DeleteShapeButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteShapeButtonPushed, true);
            app.DeleteShapeButton.Enable = 'off';
            app.DeleteShapeButton.Text = 'Delete';
            app.SaveButton = uibutton(app.ProcessingPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.pcSegDistPanel = uipanel(app.ProcessingPanel);
            app.pcSegDistPanel.Title = 'pcSegDist';
            app.ClusterButton = uibutton(app.pcSegDistPanel, 'push');
            app.ClusterButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterButtonPushed, true);
            app.ClusterButton.Enable = 'off';
            app.ClusterButton.Text = 'Cluster';
            app.ClusterDistanceEditFieldLabel = uilabel(app.pcSegDistPanel);
            app.ClusterDistanceEditFieldLabel.Text = 'Cluster Distance';
            app.ClusterDistanceEditField = uieditfield(app.pcSegDistPanel, 'numeric');
            app.ClusterDistanceEditField.Tooltip = {'Set the ''minDistance'' input of the "pcsegdist" function used to cluster the points'};
            app.ClusterDistanceEditField.Value = 0.2;
            app.pcFitShapePanel = uipanel(app.ProcessingPanel);
            app.pcFitShapePanel.Title = 'pcFitShape';
            app.ShapeDropDownLabel = uilabel(app.pcFitShapePanel);
            app.ShapeDropDownLabel.Text = 'Shape';
            app.ShapeDropDown = uidropdown(app.pcFitShapePanel);
            app.ShapeDropDown.Items = {'Plane', 'Sphere', 'Cylinder'};
            app.ShapeDropDown.ItemsData = [1 2 3];
            app.ShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ShapeDropDownValueChanged, true);
            app.ShapeDropDown.Tooltip = {'Choose which fitting function to use:'; 'Plane = "pcfitplane"'; 'Sphere = "pcfitsphere"'; 'Cylinder= "pcfitcylinder"'};
            app.ShapeDropDown.Value = 1;
            app.FitShapeButton = uibutton(app.pcFitShapePanel, 'push');
            app.FitShapeButton.ButtonPushedFcn = createCallbackFcn(app, @FitShapeButtonPushed, true);
            app.FitShapeButton.Enable = 'off';
            app.FitShapeButton.Text = {'Fit'; 'Shape'};
            app.ReferenceVectorDropDownLabel = uilabel(app.pcFitShapePanel);
            app.ReferenceVectorDropDownLabel.Text = 'Reference Vector';
            app.ReferenceVectorDropDown = uidropdown(app.pcFitShapePanel);
            app.ReferenceVectorDropDown.Items = {'None', 'X', 'Y', 'Z'};
            app.ReferenceVectorDropDown.ItemsData = {'1', '2', '3', '4'};
            app.ReferenceVectorDropDown.Tooltip = {'Set the ''referenceVector'' input of the "pcfitshape" function used to fit the points into a shape'};
            app.ReferenceVectorDropDown.Value = '1';
            app.MaxDistanceEditFieldLabel = uilabel(app.pcFitShapePanel);
            app.MaxDistanceEditFieldLabel.Text = 'Max Distance';
            app.MaxDistanceEditField = uieditfield(app.pcFitShapePanel, 'numeric');
            app.MaxDistanceEditField.Tooltip = {'Set the ''maxDistance'' input of the "pcfitshape" function used to fit the points into a shape'};
            app.MaxDistanceEditField.Value = 0.2;
            app.ModelInfoTextAreaLabel = uilabel(app.pcFitShapePanel);
            app.ModelInfoTextAreaLabel.Text = 'Model Info';
            app.ModelInfoTextArea = uitextarea(app.pcFitShapePanel);
            app.ModelInfoTextArea.Editable = 'off';
            app.OutputMessagePanel = uipanel(app.ProcessingPanel);
            app.OutputMessagePanel.Title = 'Output Message';
            app.ErrorMsg = uitextarea(app.OutputMessagePanel);
            app.ErrorMsg.Editable = 'off';
            % Show the figure after all components are created
            app.PointCloudFitAndClusterAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PointCloudFitAndClusterApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PointCloudFitAndClusterAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PointCloudFitAndClusterAppUIFigure)
        end
    end
end